classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        ShowDetectsCheckBox   matlab.ui.control.CheckBox
        COMPortDropDownLabel  matlab.ui.control.Label
        COMPortDropDown       matlab.ui.control.DropDown
        StartButton           matlab.ui.control.Button
        StopButton            matlab.ui.control.Button
        RightPanel            matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
        SaveCSVFileButton     matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        stop = false;
        data = [];
   end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.COMPortDropDown.Items = seriallist();
           delete(instrfind);

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {611, 611};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
        
            app.stop = false;
        s = serial(app.COMPortDropDown.Value); % change the COM Port number as needed
    
        s.InputBufferSize = 50; % read only one byte every time
        s.Terminator = 'CR/LF'
        s.BaudRate = 115200;
        try
            fopen(s);
        catch err
            fclose(instrfind);
            disp('x');
            return;
           % error('Make sure you select the correct COM Port where the Arduino is connected.');
        end
        
        
        datasets=5;
        dctr=0;
        
        
        app.data = NaN(datasets,1000);

        detect = false;
        Fs=125;
        
        Ts = (1/Fs)/2; % Sampling time (s)
        i = 0;
        t = 0;
        tic % Start timer
        
        
        buds={};
        tic;
        while (1)
            i = i + 1;
            %% Read buffer data
              
                   lines = fscanf(s);
                   
                   p =  sscanf(lines,'%f %f %lu %f %f');
                
                
                   for j = 1:datasets
                        if length(p) ==datasets
                            app.data(j,i) = p(j);
                        elseif i>1
                           app.data(j,i) = app.data(j,i-1);
                        else
                            app.data(j,i)=0;
                        end
                   end
            t(i) = app.data(end,i)/1000;
            
            t_0 = toc;
            t_1 = t_0;
            while((t_1-t_0)<Ts)
                t_1=toc;
            end
            %% Plot live data
            if i > 1
                line(app.UIAxes,[t(i-1) t(i)],[app.data(1,i-1) app.data(1,i)],'Color','red')
                line(app.UIAxes,[t(i-1) t(i)],[app.data(2,i-1) app.data(2,i)],'Color','blue')
                
                
                line(app.UIAxes2,[t(i-1) t(i)],[app.data(4,i-1) app.data(4,i)],'Color','green')
        
               
                if (app.data(3,i-1)<app.data(3,i))
                    
                    if(app.ShowDetectsCheckBox.Value==1)
                        line(app.UIAxes,[t(i) t(i)],[app.data(2,i) app.data(2,i)],'Color','magenta','Marker','*','Tag','detects')
                    else
                        line(app.UIAxes,[t(i) t(i)],[app.data(2,i) app.data(2,i)],'Color','magenta','Marker','none','Tag','detects')

                    end
                end
                drawnow
                %axis auto
            end
            
            if (app.stop)
                app.data= app.data(:,all(~isnan(app.data))); 
                break;
            end
            
end
    fclose(s);
    delete (s);

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stop = true;
        end

        % Button pushed function: SaveCSVFileButton
        function SaveCSVFileButtonPushed(app, event)
            filt = {'*.csv';'*.*'};
            [file,path,indx] = uiputfile(filt,'Save new Log File','new');
            filename = fullfile(path,file);
            
            if isequal(file,0) || isequal(path,0)
                 return;
            end
            csvwrite(filename,app.data.');
        end

        % Value changed function: ShowDetectsCheckBox
        function ShowDetectsCheckBoxValueChanged(app, event)
            value = app.ShowDetectsCheckBox.Value;
            dataObjs = app.UIAxes.Children;
            detMarkers = findobj(dataObjs,'Tag','detects');
            if(value == 1)
                set(detMarkers,'Marker','*');
            else
                set(detMarkers,'Marker','none');
            end
            
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ShowDetectsCheckBox = uicheckbox(app.LeftPanel);
            app.ShowDetectsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowDetectsCheckBoxValueChanged, true);
            app.ShowDetectsCheckBox.Text = 'Show Detects';
            app.ShowDetectsCheckBox.Value = true;
            app.COMPortDropDownLabel = uilabel(app.LeftPanel);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.LeftPanel);
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.LeftPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Voltage')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'RR')
            app.SaveCSVFileButton = uibutton(app.RightPanel, 'push');
            app.SaveCSVFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCSVFileButtonPushed, true);
            app.SaveCSVFileButton.Text = ' Save CSV File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end